classdef otmapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptimusViadutoUIFigure          matlab.ui.Figure
        ArquivoMenu                     matlab.ui.container.Menu
        NovoMenu                        matlab.ui.container.Menu
        AbrirMenu                       matlab.ui.container.Menu
        SalvarMenu                      matlab.ui.container.Menu
        AjudaMenu                       matlab.ui.container.Menu
        TutorialMenu                    matlab.ui.container.Menu
        SobreMenu                       matlab.ui.container.Menu
        GridLayoutTotal                 matlab.ui.container.GridLayout
        GridLayoutSuperior              matlab.ui.container.GridLayout
        DefinicoesPanel                 matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        IniciarotimizacaoButton         matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        UsarpadraoParametrosdoMOPSOCheckBox  matlab.ui.control.CheckBox
        CarregarParametrosdoMOPSOButton  matlab.ui.control.Button
        ParametrosdoMOPSOEditField      matlab.ui.control.EditField
        UsarpadraoConfiguracoesgeraisCheckBox  matlab.ui.control.CheckBox
        ParametrosdoMOPSOEditFieldLabel  matlab.ui.control.Label
        CarregarConfiguracoesgeraisButton  matlab.ui.control.Button
        ConfiguracoesgeraisEditField    matlab.ui.control.EditField
        UsarpadraoVidautilCheckBox      matlab.ui.control.CheckBox
        CarregarVidautilButton          matlab.ui.control.Button
        VidautilEditField               matlab.ui.control.EditField
        UsarpadraoImpactoambientalCheckBox  matlab.ui.control.CheckBox
        CarregarImpactoambientalButton  matlab.ui.control.Button
        ImpactoambientalEditField       matlab.ui.control.EditField
        UsarpadraoCustosdosservicosCheckBox  matlab.ui.control.CheckBox
        CarregarCustosdosservicosButton  matlab.ui.control.Button
        CustosdosservicosEditField      matlab.ui.control.EditField
        UsarpadraoPopulacaoinicialCheckBox  matlab.ui.control.CheckBox
        CarregarPopulacaoinicialButton  matlab.ui.control.Button
        PopulacaoinicialEditField       matlab.ui.control.EditField
        UsarpadraoGeradordemodeloCheckBox  matlab.ui.control.CheckBox
        CarregarGeradordemodeloButton   matlab.ui.control.Button
        GeradordemodeloEditField        matlab.ui.control.EditField
        CarregarProblemaButton          matlab.ui.control.Button
        ProblemaEditField               matlab.ui.control.EditField
        ConfiguraesgeraisEditFieldLabel  matlab.ui.control.Label
        VidatilEditFieldLabel           matlab.ui.control.Label
        ImpactoambientalLabel           matlab.ui.control.Label
        CustosdosserviosEditFieldLabel  matlab.ui.control.Label
        ProblemaLabel                   matlab.ui.control.Label
        ConfiguraesdogeradordepopulaoinicialLabel  matlab.ui.control.Label
        GeradordemodeloLabel            matlab.ui.control.Label
        PerformancedaotimizaoPanel      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        GridLayoutInferior              matlab.ui.container.GridLayout
        DesempenhodassoluestimasPanel   matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        ProblemaPanel                   matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        GridLayoutRodarPe               matlab.ui.container.GridLayout
        CODE_NAME11                     matlab.ui.control.Image
        NsolucaoEditField               matlab.ui.control.NumericEditField
        VersolucaoNButton               matlab.ui.control.Button
        VerdiagramadesolucoesButton     matlab.ui.control.Button
        PararprocessoButton             matlab.ui.control.Button
        TextstatusLabel                 matlab.ui.control.Label
        EstadoLabel                     matlab.ui.control.Label
    end

    
    properties (Access = public)
        diretorioatual
        installdir
        problema
        parametros_gerador_modelo
        parametros_gerador_modelo_relativo
        custos
        emissoes
        coeficientes_VUP
        viaduto_padrao
        parametros_MOPSO
        pausar_flag
        otm_pausada
        items_desabilitados
        leitura_definicoes_concluida
        populacao_inicial_concluida
        min_or_max
        upper_bound
        log_hv_freq
        vaos_unicos
        quantidade_type
        minvalue
        maxvalue
        discvalue
        populacao
        archive
        n_archive
        archive_type
        n_archive_type
        t
        hypervolume
    end
    
    methods (Access = public)
        
        function abilita_desabilita_editfild(app, event)
            tag = event.Source.Tag;
            editfildname = [tag 'EditField'];
            buttonname = ['Carregar' tag 'Button'];
            if ~event.Value
                app.(editfildname).Enable       = true;
                app.(editfildname).Editable     = true;
                app.(buttonname).Enable = true;
                app.(editfildname).Value = '';
            else
                app.(editfildname).Enable       = false;
                app.(editfildname).Editable     = false;
                app.(editfildname).Value = 'Valores padrões';
                app.(buttonname).Enable = false;
            end
        end

        function ler_definicoes(app)
            try
                sucesso_leitura_problema = ler_e_desenhar_problema(app);
                if not(sucesso_leitura_problema)
                    error('A leitura do problema falhou');
                end


                if app.UsarpadraoGeradordemodeloCheckBox.Value
                    config_gerador_modelo_padrao
                    app.parametros_gerador_modelo = parametros_gerador_modelo;
                else
                    app.parametros_gerador_modelo = readstruct([app.GeradordemodeloEditField.Value]);
                end

                if app.UsarpadraoPopulacaoinicialCheckBox.Value
                    config_gerador_modelo_relativo_padrao
                    app.parametros_gerador_modelo_relativo = parametros_gerador_modelo_relativo;
                else
                    app.parametros_gerador_modelo_relativo = readstruct([app.PopulacaoinicialEditField.Value]);
                end

                if app.UsarpadraoCustosdosservicosCheckBox.Value
                    config_custos_padrao
                    app.custos = custos;
                else
                    app.custos = readstruct([app.CustosdosservicosEditField.Value]);
                end

                if app.UsarpadraoImpactoambientalCheckBox.Value
                    config_emissoes_padrao
                    app.emissoes = emissoes;
                else
                    app.emissoes = readstruct([app.ImpactoambientalEditField.Value]);
                end

                if app.UsarpadraoVidautilCheckBox.Value
                    config_coeficientes_VUP_padrao
                    app.coeficientes_VUP = coeficientes_VUP;
                else
                    app.coeficientes_VUP = readstruct([app.VidautilEditField.Value]);
                end

                if app.UsarpadraoConfiguracoesgeraisCheckBox.Value
                    config_viaduto_padrao
                    app.viaduto_padrao = viaduto;
                else
                    app.viaduto_padrao = readstruct([app.ConfiguracoesgeraisEditField.Value]);
                end

                if app.UsarpadraoParametrosdoMOPSOCheckBox.Value
                    config_parametros_MOPSO_padrao
                    app.parametros_MOPSO = parametros_MOPSO;
                else
                    app.parametros_MOPSO = readstruct([app.ParametrosdoMOPSOEditField.Value]);
                end
                
                app.leitura_definicoes_concluida = true;
            catch ME
                uialert(app.OptimusViadutoUIFigure, 'Aconteceu algo inesperado durante a leitura dos arquivos. Verifique o caminho para os arquivos e tente novamente.', 'Erro na leitura');
                return;
            end


        end

        function inicia_inicializa_populacao(app)
            app.CODE_NAME11.Visible = 'on';
            % Se chegou até aqui é porque a leitura dos arquivos teve sucesso
            app.IniciarotimizacaoButton.Enable = false;
            %app.GridLayout.
            app.PararprocessoButton.Enable = true;


            [pop_size, archive_size, bottom, top, w_min, w_max, c1, c2, c3i, p1, maxgen, mut_prob, eta_m] = individualiza_parametros_MOPSO(app.parametros_MOPSO);

            relatorio_tempo = false;
            relatorio_erro = false;
            config_draw_off

            [app.vaos_unicos, app.quantidade_type, app.minvalue, app.maxvalue, app.discvalue, app.populacao] = inicializa_populacao(app.parametros_gerador_modelo, app.problema.impedido, pop_size, app.viaduto_padrao, app.problema.terreno, app.problema.greide, app.parametros_gerador_modelo_relativo, app.custos, app.emissoes, app.coeficientes_VUP, relatorio_tempo, relatorio_erro, config_draw, app);
            app.CODE_NAME11.Visible = 'off';
        end

        function inicia_processo_otimizacao(app)
            app.CODE_NAME11.Visible = 'on';
            relatorio_tempo = false;
            relatorio_erro = false;
            
            app.min_or_max = [1,1,-1]; % 1 para minimizar e -1 para maximizar
            app.upper_bound = [5000000 2000 0];
            app.log_hv_freq = 10;

            config_draw_off

            [pop_size, archive_size, bottom, top, w_min, w_max, c1, c2, c3i, p1, maxgen, mut_prob, eta_m] = individualiza_parametros_MOPSO(app.parametros_MOPSO);
            %% Inicializa o arquivo geral
            app.archive = cell(1,archive_size);

            app.n_archive = 0;

            %% Atualiza o arquivo geral

            [app.archive, app.n_archive] = update_archive(app.populacao, app.n_archive, app.archive, app.min_or_max, bottom);

            %% Inicializa o arquivo de cada tipo de particula
            [tamanho_archive_type, ~] = divide_populacao_por_grupo_vao(app.vaos_unicos, archive_size);
            app.archive_type = cell(1,app.quantidade_type);
            for i = 1:app.quantidade_type
                app.archive_type{i} = cell(1,tamanho_archive_type(i));
            end
            app.n_archive_type = [0, 0, 0];

            %% Atualiza o arquivo de cada tipo de particula

            [app.archive_type, app.n_archive_type] = update_archive_type(app.populacao, app.quantidade_type, app.archive_type, app.n_archive_type, app.min_or_max, bottom);


            %% Inicializa o armazenador de hypervolume

            app.hypervolume = zeros(1,fix(maxgen/app.log_hv_freq));


            %% Processo iterativo - MAIN LOOP
            app.t = 1;
            app.otm_pausada = false;
            processo_otimizacao(app)

            app.CODE_NAME11.Visible = 'off';
        end

        function processo_otimizacao(app)
            app.CODE_NAME11.Visible = 'on';
            relatorio_tempo = false;
            relatorio_erro = false;
            config_draw_off
            [pop_size, archive_size, bottom, top, w_min, w_max, c1, c2, c3i, p1, maxgen, mut_prob, eta_m] = individualiza_parametros_MOPSO(app.parametros_MOPSO);
            while app.t <= maxgen
                fprintf( [datestr(now,'HH:MM:SS') ': Iteração número ' num2str(app.t) '.\n']);
                app.TextstatusLabel.Text = ['Desenvolvendo iteração número ' num2str(app.t) '.'];drawnow;
                [app.populacao, app.archive, app.n_archive, app.archive_type, app.n_archive_type] = iteracao_MOPSO(app.populacao, app.archive, app.n_archive, app.quantidade_type, app.archive_type, app.n_archive_type, w_max, w_min, app.t, maxgen, c3i, top, p1, c1, c2, app.minvalue, app.maxvalue, app.discvalue, mut_prob, eta_m, app.problema.impedido, app.parametros_gerador_modelo, app.viaduto_padrao, app.problema.terreno, app.problema.greide, app.custos, app.emissoes, app.coeficientes_VUP, relatorio_tempo, relatorio_erro, app.min_or_max, bottom, config_draw);
                app.TextstatusLabel.Text = ['Desenvolvendo iteração número ' num2str(app.t) '.'];drawnow;


                %% Calcula o hypervolume

                if rem(app.t,app.log_hv_freq) == 0
                    app.hypervolume(fix(app.t/app.log_hv_freq)) = calc_hypervolume(app.archive,app.n_archive, app.min_or_max, app.upper_bound);
                    fprintf( [datestr(now,'HH:MM:SS') ': Hipervolume: ' num2str(app.hypervolume(fix(app.t/app.log_hv_freq))/1E9) ' G.\n']);
                end


                %% Desenha
                draw_resultados_scatter(app.archive,app.UIAxes3)
      
                if and(rem(app.t,app.log_hv_freq) == 0,fix(app.t/app.log_hv_freq)>1)
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,[app.t-app.log_hv_freq app.t], [app.hypervolume(fix(app.t/app.log_hv_freq)-1) app.hypervolume(fix(app.t/app.log_hv_freq))],'color',[0 0.4470 0.7410])
                    hold(app.UIAxes,'off')
                end


                %% Itera contador
                app.t = app.t + 1;

                %% Lida com a interface

                if app.pausar_flag == true
                    set_interface_otm_pausada(app)
                    return
                end

            end
            app.TextstatusLabel.Text = ['Otimização concluída. Hipervolume final ' num2str(app.hypervolume(end)/1E9) ' G.'];drawnow;
            set_interface_otm_pausando(app)
            app.CODE_NAME11.Visible = 'off';
        end
        
        function set_interface_otm_pausando(app)
            app.PararprocessoButton.Enable = 'off';
            app.VerdiagramadesolucoesButton.Enable = 'on';
            app.VersolucaoNButton.Enable = 'on';
            app.NsolucaoEditField.Enable = 'on';
            app.NsolucaoEditField.Editable = 'on';
            drawnow;
        end

        function set_interface_otm_pausada(app)
            app.PararprocessoButton.Text = 'Continuar otimização';
            app.PararprocessoButton.Enable = 'on';
            app.TextstatusLabel.Text = 'Otimização pausada pelo usuário.';
            app.NovoMenu.Enable = 'on';
            app.AbrirMenu.Enable = 'on';
            app.SalvarMenu.Enable = 'on';
            app.SalvarMenu.Tooltip = 'Salvar o estado atual do processo de otimização';

            app.pausar_flag = false;
            app.otm_pausada = true;
            app.CODE_NAME11.Visible = 'off';
            drawnow;
        end

        function pararprocesso(app)
            app.pausar_flag = true;
            if app.populacao_inicial_concluida == false % Caso a otimização não tenha sido iniciada
                % Suspende a criação de soluções iniciais
                app.TextstatusLabel.Text = 'Suspendendo o processo...';
                app.PararprocessoButton.Enable = 'off';
                set(app.items_desabilitados,'Enable','on');

                drawnow;
            else % Caso a otimização ja tenha sido iniciada
                if app.otm_pausada % Caso a otimização já esteja pausada
                    % Resume o processo de otimização
                    app.pausar_flag = false;
                    app.otm_pausada = false;
                    app.PararprocessoButton.Text = 'Parar processo';
                    app.VerdiagramadesolucoesButton.Enable = 'off';
                    app.VersolucaoNButton.Enable = 'off';
                    app.NsolucaoEditField.Enable = 'off';
                    app.NsolucaoEditField.Editable = 'off';
                    app.NovoMenu.Enable = 'off';
                    app.AbrirMenu.Enable = 'off';
                    app.SalvarMenu.Enable = 'off';
                    drawnow;
                    processo_otimizacao(app)
                else % Caso a otimização esteja rodando
                    % Pausa a otimização
                    app.TextstatusLabel.Text = 'Suspendendo o processo...';
                    set_interface_otm_pausando(app)
                end
            end


        end


        function ver_solucao(app,i,t)
            if or(i > app.n_archive, i < 1)
                uialert(app.OptimusViadutoUIFigure, ['O número da solução deve ser maior que 1 e menor que ' num2str(app.n_archive) '.'], 'Solução inexistente','Icon','warning');
                return;
            end
            tabela_resultados_gui(i, t, app.archive{i}.variaveis, app.archive{i}.fitness)
            drawnow;
            pause(.2)
            
            fig = figure;
            set(fig,'Name',['Visualização da solução ' num2str(i) ' da iteração ' num2str(t)],'NumberTitle','off')
            
            draw_viaduto_completo(axes(fig),app.archive{i}.viaduto, app.problema.greide, app.problema.terreno, app.problema.impedido, app.archive{i}.info)

        end

        function sucesso_leitura_problema = ler_e_desenhar_problema(app)
            try
                app.problema = readstruct([app.ProblemaEditField.Value]);
                draw_problema(app.UIAxes2,app.problema.greide, app.problema.impedido, app.problema.terreno)
                sucesso_leitura_problema = true;
            catch ME
                uialert(app.OptimusViadutoUIFigure, 'Aconteceu algo inesperado durante a interpretação do problema. Verifique o arquivo de definições do problema e tente novamente.', 'Erro na interpretação');
                sucesso_leitura_problema = false;
                return;
            end
        end
        
        function salvar_otimizacao(app)
            cd(fullfile(app.installdir));
            %cd(fullfile(getenv('USERPROFILE'), 'Documents'));
            %if isdeployed
            %    cd(fullfile(ctfroot));
            %end
            df = figure('Renderer', 'painters', 'Position', [-10000 -10000 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file,path] = uiputfile('*.otmv');
            delete(df); %delete the dummy figure
            cd(app.diretorioatual);

            if (ischar(path))

                problema = app.problema;
                parametros_gerador_modelo = app.parametros_gerador_modelo;
                parametros_gerador_modelo_relativo = app.parametros_gerador_modelo_relativo;
                custos = app.custos;
                emissoes = app.emissoes;
                coeficientes_VUP = app.coeficientes_VUP;
                viaduto_padrao = app.viaduto_padrao;
                parametros_MOPSO = app.parametros_MOPSO;
                min_or_max = app.min_or_max;
                upper_bound = app.upper_bound;
                log_hv_freq = app.log_hv_freq;
                populacao = app.populacao;
                archive = app.archive;
                n_archive = app.n_archive;
                quantidade_type = app.quantidade_type;
                archive_type = app.archive_type;
                n_archive_type = app.n_archive_type;
                minvalue = app.minvalue;
                maxvalue = app.maxvalue;
                discvalue = app.discvalue;
                t = app.t;
                hypervolume = app.hypervolume;


                save([path file],'problema','parametros_gerador_modelo','parametros_gerador_modelo_relativo',...
                    'custos','emissoes','coeficientes_VUP','viaduto_padrao','parametros_MOPSO',...
                    'min_or_max','upper_bound','log_hv_freq','populacao','archive','n_archive',...
                    'quantidade_type','archive_type','n_archive_type','minvalue','maxvalue','discvalue',...
                    't','hypervolume')
            end
        end

        function abrir_otimizacao(app)
            selection = 'Sim';
            if app.populacao_inicial_concluida == true
                selection = uiconfirm(app.OptimusViadutoUIFigure,'Ao abrir uma otimização salva o progresso atual será perdido. Você deseja continuar mesmo assim?','Carregar o progresso de uma otimização já iniciada', ...
                    'Options',{'Sim','Não'}, ...
                    'DefaultOption',2,'CancelOption',2);
            end
            if strcmp(selection,'Sim')
                cd(fullfile(app.installdir));
                %cd(fullfile(getenv('USERPROFILE'), 'Documents'));
                %if isdeployed
                %    cd(fullfile(ctfroot));
                %end
                df = figure('Renderer', 'painters', 'Position', [-10000 -10000 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [file,path] = uigetfile('*.otmv');
                delete(df); %delete the dummy figure
                cd(app.diretorioatual);

                if (ischar(path))
                    load([path file],'-mat');
                    app.problema = problema;
                    app.parametros_gerador_modelo = parametros_gerador_modelo;
                    app.parametros_gerador_modelo_relativo = parametros_gerador_modelo_relativo;
                    app.custos = custos;
                    app.emissoes = emissoes;
                    app.coeficientes_VUP = coeficientes_VUP;
                    app.viaduto_padrao = viaduto_padrao;
                    app.parametros_MOPSO = parametros_MOPSO;
                    app.min_or_max = min_or_max;
                    app.upper_bound = upper_bound;
                    app.log_hv_freq = log_hv_freq;
                    app.populacao = populacao;
                    app.archive = archive;
                    app.n_archive = n_archive;
                    app.quantidade_type = quantidade_type;
                    app.archive_type = archive_type;
                    app.n_archive_type = n_archive_type;
                    app.minvalue = minvalue;
                    app.maxvalue = maxvalue;
                    app.discvalue = discvalue;
                    app.t = t;
                    app.hypervolume = hypervolume;


                    children = get(app.GridLayout,'Children');
                    app.items_desabilitados = findobj(children,'Enable','on');
                    set(app.items_desabilitados,'Enable','off');
                    app.IniciarotimizacaoButton.Enable = 'off';

                    set_interface_otm_pausando(app)
                    set_interface_otm_pausada(app)
                    app.TextstatusLabel.Text = ['Otimização já iniciada carregada do arquivo ' file];

                    editfields = findobj(children,'type','uiEditField');
                    set(editfields,'Value',[path file]);

                    checkboxes = findobj(children,'type','uiCheckBox');
                    set(checkboxes,'Value',false);

                    app.populacao_inicial_concluida = true;


                    % Recupera desenhos
                    draw_problema(app.UIAxes2,app.problema.greide, app.problema.impedido, app.problema.terreno)

                    draw_resultados_scatter(app.archive,app.UIAxes3)

                    cla(app.UIAxes)
                    if fix(app.t/app.log_hv_freq) >= 2
                        hold(app.UIAxes,'on')
                        for l = 1 : (fix(app.t/app.log_hv_freq) - 1)
                            plot(app.UIAxes,[l*app.log_hv_freq l*app.log_hv_freq+app.log_hv_freq], [app.hypervolume(l) app.hypervolume(l+1)],'color',[0 0.4470 0.7410])
                        end
                        hold(app.UIAxes,'off')
                    end
                end
            end
        end

        function reseta_interface(app)
            selection = 'Sim';
            if app.populacao_inicial_concluida == true
                selection = uiconfirm(app.OptimusViadutoUIFigure,'Ao criar uma nova otimização o progresso atual será perdido. Você deseja continuar mesmo assim?','Criar novo processo de otimização', ...
                    'Options',{'Sim','Não'}, ...
                    'DefaultOption',2,'CancelOption',2);
            end
            if strcmp(selection,'Sim')
                app.leitura_definicoes_concluida = false;
                app.populacao_inicial_concluida = false;

                app.IniciarotimizacaoButton.Enable = 'on';
                app.TextstatusLabel.Text = 'Aguardando início da otimização';
                app.PararprocessoButton.Enable = 'off';
                app.PararprocessoButton.Text = 'Parar Processo';
                app.VerdiagramadesolucoesButton.Enable = 'off';
                app.VersolucaoNButton.Enable = 'off';
                app.NsolucaoEditField.Enable = 'off';
                app.NsolucaoEditField.Value = 1;


                cla(app.UIAxes)
                cla(app.UIAxes2)
                cla(app.UIAxes3)

                app.ProblemaLabel.Enable = 'on';
                app.ProblemaEditField.Enable = 'on';
                app.ProblemaEditField.Value = '';
                app.CarregarProblemaButton.Enable = 'on';

                app.GeradordemodeloLabel.Enable = 'on';
                app.GeradordemodeloEditField.Enable = 'off';
                app.GeradordemodeloEditField.Value = 'Valores padrões';
                app.CarregarGeradordemodeloButton.Enable = 'off';
                app.UsarpadraoGeradordemodeloCheckBox.Enable = 'on';
                app.UsarpadraoGeradordemodeloCheckBox.Value = true;

                app.ConfiguraesdogeradordepopulaoinicialLabel.Enable = 'on';
                app.PopulacaoinicialEditField.Enable = 'off';
                app.PopulacaoinicialEditField.Value = 'Valores padrões';
                app.CarregarPopulacaoinicialButton.Enable = 'off';
                app.UsarpadraoPopulacaoinicialCheckBox.Enable = 'on';
                app.UsarpadraoPopulacaoinicialCheckBox.Value = true;

                app.CustosdosserviosEditFieldLabel.Enable = 'on';
                app.CustosdosservicosEditField.Enable = 'off';
                app.CustosdosservicosEditField.Value = 'Valores padrões';
                app.CarregarCustosdosservicosButton.Enable = 'off';
                app.UsarpadraoCustosdosservicosCheckBox.Enable = 'on';
                app.UsarpadraoCustosdosservicosCheckBox.Value = true;

                app.ImpactoambientalLabel.Enable = 'on';
                app.ImpactoambientalEditField.Enable = 'off';
                app.ImpactoambientalEditField.Value = 'Valores padrões';
                app.CarregarImpactoambientalButton.Enable = 'off';
                app.UsarpadraoImpactoambientalCheckBox.Enable = 'on';
                app.UsarpadraoImpactoambientalCheckBox.Value = true;

                app.VidatilEditFieldLabel.Enable = 'on';
                app.VidautilEditField.Enable = 'off';
                app.VidautilEditField.Value = 'Valores padrões';
                app.CarregarVidautilButton.Enable = 'off';
                app.UsarpadraoVidautilCheckBox.Enable = 'on';
                app.UsarpadraoVidautilCheckBox.Value = true;

                app.ConfiguraesgeraisEditFieldLabel.Enable = 'on';
                app.ConfiguracoesgeraisEditField.Enable = 'off';
                app.ConfiguracoesgeraisEditField.Value = 'Valores padrões';
                app.CarregarConfiguracoesgeraisButton.Enable = 'off';
                app.UsarpadraoConfiguracoesgeraisCheckBox.Enable = 'on';
                app.UsarpadraoConfiguracoesgeraisCheckBox.Value = true;

                app.ParametrosdoMOPSOEditFieldLabel.Enable = 'on';
                app.ParametrosdoMOPSOEditField.Enable = 'off';
                app.ParametrosdoMOPSOEditField.Value = 'Valores padrões';
                app.CarregarParametrosdoMOPSOButton.Enable = 'off';
                app.UsarpadraoParametrosdoMOPSOCheckBox.Enable = 'on';
                app.UsarpadraoParametrosdoMOPSOCheckBox.Value = true;
            end

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %movegui(app.OptimusViadutoUIFigure, 'center');
            if and(isdeployed,ispc) % Stand-alone mode.
                [status, result] = system('path');
                app.installdir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            else % MATLAB mode.
                app.installdir = pwd;
            end
            app.diretorioatual = pwd;
            app.populacao_inicial_concluida = false;
        end

        % Button pushed function: CarregarProblemaButton
        function CarregarProblemaButtonPushed(app, event)
            cd(fullfile(app.installdir));
            %cd(fullfile(getenv('USERPROFILE'), 'Documents'));
            %if isdeployed
            %    cd(fullfile(ctfroot));
            %end
            df = figure('Renderer', 'painters', 'Position', [-10000 -10000 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file,path] = uigetfile('*.xml');
            delete(df); %delete the dummy figure
            cd(app.diretorioatual);
            if (ischar(path))
                app.ProblemaEditField.Value = [path file];
                sucesso_leitura_problema = ler_e_desenhar_problema(app);
            end
        end

        % Button pushed function: CarregarGeradordemodeloButton
        function CarregarGeradordemodeloButtonPushed(app, event)
            cd(fullfile(app.installdir));
            %cd(fullfile(getenv('USERPROFILE'), 'Documents'));
            %if isdeployed
            %    cd(fullfile(ctfroot));
            %end
            df = figure('Renderer', 'painters', 'Position', [-10000 -10000 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file,path] = uigetfile('*.xml');
            delete(df); %delete the dummy figure
            cd(app.diretorioatual);
            if (ischar(path))
                app.GeradordemodeloEditField.Value = [path file];
            end
        end

        % Button pushed function: CarregarVidautilButton
        function CarregarVidautilButtonPushed(app, event)
            cd(fullfile(app.installdir));
            %cd(fullfile(getenv('USERPROFILE'), 'Documents'));
            %if isdeployed
            %    cd(fullfile(ctfroot));
            %end
            df = figure('Renderer', 'painters', 'Position', [-10000 -10000 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file,path] = uigetfile('*.xml');
            delete(df); %delete the dummy figure
            cd(app.diretorioatual);
            if (ischar(path))
                app.VidautilEditField.Value = [path file];
            end
        end

        % Button pushed function: CarregarImpactoambientalButton
        function CarregarImpactoambientalButtonPushed(app, event)
            cd(fullfile(app.installdir));
            %cd(fullfile(getenv('USERPROFILE'), 'Documents'));
            %if isdeployed
            %    cd(fullfile(ctfroot));
            %end
            df = figure('Renderer', 'painters', 'Position', [-10000 -10000 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file,path] = uigetfile('*.xml');
            delete(df); %delete the dummy figure
            cd(app.diretorioatual);
            if (ischar(path))
                app.ImpactoambientalEditField.Value = [path file];
            end
        end

        % Button pushed function: CarregarCustosdosservicosButton
        function CarregarCustosdosservicosButtonPushed(app, event)
            cd(fullfile(app.installdir));
            %cd(fullfile(getenv('USERPROFILE'), 'Documents'));
            %if isdeployed
            %    cd(fullfile(ctfroot));
            %end
            df = figure('Renderer', 'painters', 'Position', [-10000 -10000 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file,path] = uigetfile('*.xml');
            delete(df); %delete the dummy figure
            cd(app.diretorioatual);
            if (ischar(path))
                app.CustosdosservicosEditField.Value = [path file];
            end
        end

        % Button pushed function: CarregarPopulacaoinicialButton
        function CarregarPopulacaoinicialButtonPushed(app, event)
            cd(fullfile(app.installdir));
            %cd(fullfile(getenv('USERPROFILE'), 'Documents'));
            %if isdeployed
            %    cd(fullfile(ctfroot));
            %end
            df = figure('Renderer', 'painters', 'Position', [-10000 -10000 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file,path] = uigetfile('*.xml');
            delete(df); %delete the dummy figure
            cd(app.diretorioatual);
            if (ischar(path))
                app.PopulacaoinicialEditField.Value = [path file];
            end
        end

        % Button pushed function: CarregarConfiguracoesgeraisButton
        function CarregarConfiguracoesgeraisButtonPushed(app, event)
            cd(fullfile(app.installdir));
            %cd(fullfile(getenv('USERPROFILE'), 'Documents'));
            %if isdeployed
            %    cd(fullfile(ctfroot));
            %end
            df = figure('Renderer', 'painters', 'Position', [-10000 -10000 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file,path] = uigetfile('*.xml');
            delete(df); %delete the dummy figure
            cd(app.diretorioatual);
            if (ischar(path))
                app.ConfiguracoesgeraisEditField.Value = [path file];
            end
        end

        % Button pushed function: CarregarParametrosdoMOPSOButton
        function CarregarParametrosdoMOPSOButtonPushed(app, event)
            cd(fullfile(app.installdir));
            %cd(fullfile(getenv('USERPROFILE'), 'Documents'));
            %if isdeployed
            %    cd(fullfile(ctfroot));
            %end
            df = figure('Renderer', 'painters', 'Position', [-10000 -10000 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file,path] = uigetfile('*.xml');
            delete(df); %delete the dummy figure
            cd(app.diretorioatual);
            if (ischar(path))
                app.ParametrosdoMOPSOEditField.Value = [path file];
            end
        end

        % Value changed function: UsarpadraoConfiguracoesgeraisCheckBox, 
        % UsarpadraoCustosdosservicosCheckBox, 
        % UsarpadraoGeradordemodeloCheckBox, 
        % UsarpadraoImpactoambientalCheckBox, 
        % UsarpadraoParametrosdoMOPSOCheckBox, 
        % UsarpadraoPopulacaoinicialCheckBox, UsarpadraoVidautilCheckBox
        function atualizaUIvalor_padrao(app, event)
            abilita_desabilita_editfild(app, event);
        end

        % Button pushed function: IniciarotimizacaoButton
        function IniciarotimizacaoButtonPushed(app, event)
            app.pausar_flag = false;
            ler_definicoes(app)


            if app.leitura_definicoes_concluida
                children = get(app.GridLayout,'Children');
                app.items_desabilitados = findobj(children,'Enable','on');
                set(app.items_desabilitados,'Enable','off');
                app.NovoMenu.Enable = 'off';
                app.AbrirMenu.Enable = 'off';
                app.SalvarMenu.Enable = 'off';


                inicia_inicializa_populacao(app);
            else
                return
            end

            if app.populacao_inicial_concluida == true
                inicia_processo_otimizacao(app);
            else
                return
            end
            

        end

        % Button pushed function: PararprocessoButton
        function PararprocessoButtonPushed(app, event)
            pararprocesso(app);
        end

        % Button pushed function: VerdiagramadesolucoesButton
        function VerdiagramadesolucoesButtonPushed(app, event)
            draw_diagrama_linhas_multtype(app.archive,22,app.t,1000)
        end

        % Button pushed function: VersolucaoNButton
        function VersolucaoNButtonPushed(app, event)
            ver_solucao(app,app.NsolucaoEditField.Value,app.t)
        end

        % Menu selected function: SobreMenu
        function SobreMenuSelected(app, event)
            otmsobre_exported(app.OptimusViadutoUIFigure.Position)
        end

        % Menu selected function: SalvarMenu
        function SalvarMenuSelected(app, event)
            salvar_otimizacao(app)
        end

        % Menu selected function: AbrirMenu
        function AbrirMenuSelected(app, event)
            abrir_otimizacao(app)
        end

        % Menu selected function: NovoMenu
        function NovoMenuSelected(app, event)
            reseta_interface(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptimusViadutoUIFigure = uifigure('Visible', 'off');
            app.OptimusViadutoUIFigure.Name = 'OptimusViaduto';
            app.OptimusViadutoUIFigure.Icon = 'Icone programa.png';
            app.ArquivoMenu = uimenu(app.OptimusViadutoUIFigure);
            app.ArquivoMenu.Text = 'Arquivo';
            app.NovoMenu = uimenu(app.ArquivoMenu);
            app.NovoMenu.MenuSelectedFcn = createCallbackFcn(app, @NovoMenuSelected, true);
            app.NovoMenu.Accelerator = 'n';
            app.NovoMenu.Text = 'Novo';
            app.AbrirMenu = uimenu(app.ArquivoMenu);
            app.AbrirMenu.MenuSelectedFcn = createCallbackFcn(app, @AbrirMenuSelected, true);
            app.AbrirMenu.Tooltip = {'Carrega um processo de otimização já iniciado'};
            app.AbrirMenu.Accelerator = 'o';
            app.AbrirMenu.Text = 'Abrir';
            app.SalvarMenu = uimenu(app.ArquivoMenu);
            app.SalvarMenu.MenuSelectedFcn = createCallbackFcn(app, @SalvarMenuSelected, true);
            app.SalvarMenu.Tooltip = {'Salvar um processo de otimização já iniciado e pausado'};
            app.SalvarMenu.Enable = 'off';
            app.SalvarMenu.Accelerator = 's';
            app.SalvarMenu.Text = 'Salvar';
            app.AjudaMenu = uimenu(app.OptimusViadutoUIFigure);
            app.AjudaMenu.Text = 'Ajuda';
            app.TutorialMenu = uimenu(app.AjudaMenu);
            app.TutorialMenu.Visible = 'off';
            app.TutorialMenu.Accelerator = 't';
            app.TutorialMenu.Text = 'Tutorial';
            app.SobreMenu = uimenu(app.AjudaMenu);
            app.SobreMenu.MenuSelectedFcn = createCallbackFcn(app, @SobreMenuSelected, true);
            app.SobreMenu.Separator = 'on';
            app.SobreMenu.Accelerator = 'o';
            app.SobreMenu.Text = 'Sobre';
            app.GridLayoutTotal = uigridlayout(app.OptimusViadutoUIFigure);
            app.GridLayoutRodarPe = uigridlayout(app.GridLayoutTotal);
            app.EstadoLabel = uilabel(app.GridLayoutRodarPe);
            app.EstadoLabel.Text = 'Estado:';
            app.TextstatusLabel = uilabel(app.GridLayoutRodarPe);
            app.TextstatusLabel.Text = 'Aguardando início da otimização.';
            app.PararprocessoButton = uibutton(app.GridLayoutRodarPe, 'push');
            app.PararprocessoButton.ButtonPushedFcn = createCallbackFcn(app, @PararprocessoButtonPushed, true);
            app.PararprocessoButton.Enable = 'off';
            app.PararprocessoButton.Text = 'Parar processo';
            app.VerdiagramadesolucoesButton = uibutton(app.GridLayoutRodarPe, 'push');
            app.VerdiagramadesolucoesButton.ButtonPushedFcn = createCallbackFcn(app, @VerdiagramadesolucoesButtonPushed, true);
            app.VerdiagramadesolucoesButton.Enable = 'off';
            app.VerdiagramadesolucoesButton.Text = 'Ver diagrama de soluções';
            app.VersolucaoNButton = uibutton(app.GridLayoutRodarPe, 'push');
            app.VersolucaoNButton.ButtonPushedFcn = createCallbackFcn(app, @VersolucaoNButtonPushed, true);
            app.VersolucaoNButton.Enable = 'off';
            app.VersolucaoNButton.Text = 'Ver solução Nº:';
            app.NsolucaoEditField = uieditfield(app.GridLayoutRodarPe, 'numeric');
            app.NsolucaoEditField.ValueDisplayFormat = '%.0f';
            app.NsolucaoEditField.Editable = 'off';
            app.NsolucaoEditField.Enable = 'off';
            app.NsolucaoEditField.Value = 1;
            app.CODE_NAME11 = uiimage(app.GridLayoutRodarPe);
            app.CODE_NAME11.Visible = 'off';
            app.CODE_NAME11.Tooltip = {'Processando...'};
            app.CODE_NAME11.ImageSource = 'Ztz7R5jKlE.gif';
            app.GridLayoutInferior = uigridlayout(app.GridLayoutTotal);
            app.ProblemaPanel = uipanel(app.GridLayoutInferior);
            app.ProblemaPanel.Title = 'Problema';
            app.UIAxes2 = uiaxes(app.ProblemaPanel);
            xlabel(app.UIAxes2, 'X (m)')
            ylabel(app.UIAxes2, 'Z (m)')
            zlabel(app.UIAxes2, 'Y (m)')
            app.DesempenhodassoluestimasPanel = uipanel(app.GridLayoutInferior);
            app.DesempenhodassoluestimasPanel.Title = 'Desempenho das soluções ótimas';
            app.UIAxes3 = uiaxes(app.DesempenhodassoluestimasPanel);
            xlabel(app.UIAxes3, 'Custo (R$)')
            ylabel(app.UIAxes3, 'Emissões (tCO_{2})')
            zlabel(app.UIAxes3, 'Vida útil (anos)')
            app.GridLayoutSuperior = uigridlayout(app.GridLayoutTotal);
            app.PerformancedaotimizaoPanel = uipanel(app.GridLayoutSuperior);
            app.PerformancedaotimizaoPanel.Title = 'Performance da otimização';
            app.UIAxes = uiaxes(app.PerformancedaotimizaoPanel);
            xlabel(app.UIAxes, 'Número da iteração')
            ylabel(app.UIAxes, 'Hipervolume')
            app.DefinicoesPanel = uipanel(app.GridLayoutSuperior);
            app.DefinicoesPanel.Title = 'Definições';
            app.GridLayout2 = uigridlayout(app.DefinicoesPanel);
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout = uigridlayout(app.Panel);
            app.GeradordemodeloLabel = uilabel(app.GridLayout);
            app.GeradordemodeloLabel.Text = 'Gerador de modelo:';
            app.ConfiguraesdogeradordepopulaoinicialLabel = uilabel(app.GridLayout);
            app.ConfiguraesdogeradordepopulaoinicialLabel.Text = 'População inicial:';
            app.ProblemaLabel = uilabel(app.GridLayout);
            app.ProblemaLabel.Text = 'Problema:';
            app.CustosdosserviosEditFieldLabel = uilabel(app.GridLayout);
            app.CustosdosserviosEditFieldLabel.Text = 'Custos dos serviços:';
            app.ImpactoambientalLabel = uilabel(app.GridLayout);
            app.ImpactoambientalLabel.Text = 'Impacto ambiental:';
            app.VidatilEditFieldLabel = uilabel(app.GridLayout);
            app.VidatilEditFieldLabel.Text = 'Vida útil:';
            app.ConfiguraesgeraisEditFieldLabel = uilabel(app.GridLayout);
            app.ConfiguraesgeraisEditFieldLabel.Text = 'Configurações gerais:';
            app.ProblemaEditField = uieditfield(app.GridLayout, 'text');
            app.CarregarProblemaButton = uibutton(app.GridLayout, 'push');
            app.CarregarProblemaButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarProblemaButtonPushed, true);
            app.CarregarProblemaButton.Text = 'Carregar';
            app.GeradordemodeloEditField = uieditfield(app.GridLayout, 'text');
            app.GeradordemodeloEditField.Editable = 'off';
            app.GeradordemodeloEditField.Enable = 'off';
            app.GeradordemodeloEditField.Value = 'Valores Padrões';
            app.CarregarGeradordemodeloButton = uibutton(app.GridLayout, 'push');
            app.CarregarGeradordemodeloButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarGeradordemodeloButtonPushed, true);
            app.CarregarGeradordemodeloButton.Enable = 'off';
            app.CarregarGeradordemodeloButton.Text = 'Carregar';
            app.UsarpadraoGeradordemodeloCheckBox = uicheckbox(app.GridLayout);
            app.UsarpadraoGeradordemodeloCheckBox.ValueChangedFcn = createCallbackFcn(app, @atualizaUIvalor_padrao, true);
            app.UsarpadraoGeradordemodeloCheckBox.Tag = 'Geradordemodelo';
            app.UsarpadraoGeradordemodeloCheckBox.Text = 'Usar padrão';
            app.UsarpadraoGeradordemodeloCheckBox.Value = true;
            app.PopulacaoinicialEditField = uieditfield(app.GridLayout, 'text');
            app.PopulacaoinicialEditField.Editable = 'off';
            app.PopulacaoinicialEditField.Enable = 'off';
            app.PopulacaoinicialEditField.Value = 'Valores padrões';
            app.CarregarPopulacaoinicialButton = uibutton(app.GridLayout, 'push');
            app.CarregarPopulacaoinicialButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarPopulacaoinicialButtonPushed, true);
            app.CarregarPopulacaoinicialButton.Enable = 'off';
            app.CarregarPopulacaoinicialButton.Text = 'Carregar';
            app.UsarpadraoPopulacaoinicialCheckBox = uicheckbox(app.GridLayout);
            app.UsarpadraoPopulacaoinicialCheckBox.ValueChangedFcn = createCallbackFcn(app, @atualizaUIvalor_padrao, true);
            app.UsarpadraoPopulacaoinicialCheckBox.Tag = 'Populacaoinicial';
            app.UsarpadraoPopulacaoinicialCheckBox.Text = 'Usar padrão';
            app.UsarpadraoPopulacaoinicialCheckBox.Value = true;
            app.CustosdosservicosEditField = uieditfield(app.GridLayout, 'text');
            app.CustosdosservicosEditField.Editable = 'off';
            app.CustosdosservicosEditField.Enable = 'off';
            app.CustosdosservicosEditField.Value = 'Valores padrões';
            app.CarregarCustosdosservicosButton = uibutton(app.GridLayout, 'push');
            app.CarregarCustosdosservicosButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarCustosdosservicosButtonPushed, true);
            app.CarregarCustosdosservicosButton.Enable = 'off';
            app.CarregarCustosdosservicosButton.Text = 'Carregar';
            app.UsarpadraoCustosdosservicosCheckBox = uicheckbox(app.GridLayout);
            app.UsarpadraoCustosdosservicosCheckBox.ValueChangedFcn = createCallbackFcn(app, @atualizaUIvalor_padrao, true);
            app.UsarpadraoCustosdosservicosCheckBox.Tag = 'Custosdosservicos';
            app.UsarpadraoCustosdosservicosCheckBox.Tooltip = {''};
            app.UsarpadraoCustosdosservicosCheckBox.Text = 'Usar padrão';
            app.UsarpadraoCustosdosservicosCheckBox.Value = true;
            app.ImpactoambientalEditField = uieditfield(app.GridLayout, 'text');
            app.ImpactoambientalEditField.Editable = 'off';
            app.ImpactoambientalEditField.Enable = 'off';
            app.ImpactoambientalEditField.Value = 'Valores padrões';
            app.CarregarImpactoambientalButton = uibutton(app.GridLayout, 'push');
            app.CarregarImpactoambientalButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarImpactoambientalButtonPushed, true);
            app.CarregarImpactoambientalButton.Enable = 'off';
            app.CarregarImpactoambientalButton.Text = 'Carregar';
            app.UsarpadraoImpactoambientalCheckBox = uicheckbox(app.GridLayout);
            app.UsarpadraoImpactoambientalCheckBox.ValueChangedFcn = createCallbackFcn(app, @atualizaUIvalor_padrao, true);
            app.UsarpadraoImpactoambientalCheckBox.Tag = 'Impactoambiental';
            app.UsarpadraoImpactoambientalCheckBox.Text = 'Usar padrão';
            app.UsarpadraoImpactoambientalCheckBox.Value = true;
            app.VidautilEditField = uieditfield(app.GridLayout, 'text');
            app.VidautilEditField.Editable = 'off';
            app.VidautilEditField.Enable = 'off';
            app.VidautilEditField.Value = 'Valores padrões';
            app.CarregarVidautilButton = uibutton(app.GridLayout, 'push');
            app.CarregarVidautilButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarVidautilButtonPushed, true);
            app.CarregarVidautilButton.Enable = 'off';
            app.CarregarVidautilButton.Text = 'Carregar';
            app.UsarpadraoVidautilCheckBox = uicheckbox(app.GridLayout);
            app.UsarpadraoVidautilCheckBox.ValueChangedFcn = createCallbackFcn(app, @atualizaUIvalor_padrao, true);
            app.UsarpadraoVidautilCheckBox.Tag = 'Vidautil';
            app.UsarpadraoVidautilCheckBox.Text = 'Usar padrão';
            app.UsarpadraoVidautilCheckBox.Value = true;
            app.ConfiguracoesgeraisEditField = uieditfield(app.GridLayout, 'text');
            app.ConfiguracoesgeraisEditField.Editable = 'off';
            app.ConfiguracoesgeraisEditField.Enable = 'off';
            app.ConfiguracoesgeraisEditField.Value = 'Valores padrões';
            app.CarregarConfiguracoesgeraisButton = uibutton(app.GridLayout, 'push');
            app.CarregarConfiguracoesgeraisButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarConfiguracoesgeraisButtonPushed, true);
            app.CarregarConfiguracoesgeraisButton.Enable = 'off';
            app.CarregarConfiguracoesgeraisButton.Text = 'Carregar';
            app.ParametrosdoMOPSOEditFieldLabel = uilabel(app.GridLayout);
            app.ParametrosdoMOPSOEditFieldLabel.Text = 'Parametros do MOPSO:';
            app.UsarpadraoConfiguracoesgeraisCheckBox = uicheckbox(app.GridLayout);
            app.UsarpadraoConfiguracoesgeraisCheckBox.ValueChangedFcn = createCallbackFcn(app, @atualizaUIvalor_padrao, true);
            app.UsarpadraoConfiguracoesgeraisCheckBox.Tag = 'Configuracoesgerais';
            app.UsarpadraoConfiguracoesgeraisCheckBox.Text = 'Usar padrão';
            app.UsarpadraoConfiguracoesgeraisCheckBox.Value = true;
            app.ParametrosdoMOPSOEditField = uieditfield(app.GridLayout, 'text');
            app.ParametrosdoMOPSOEditField.Editable = 'off';
            app.ParametrosdoMOPSOEditField.Enable = 'off';
            app.ParametrosdoMOPSOEditField.Value = 'Valores padrões';
            app.CarregarParametrosdoMOPSOButton = uibutton(app.GridLayout, 'push');
            app.CarregarParametrosdoMOPSOButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarParametrosdoMOPSOButtonPushed, true);
            app.CarregarParametrosdoMOPSOButton.Enable = 'off';
            app.CarregarParametrosdoMOPSOButton.Text = 'Carregar';
            app.UsarpadraoParametrosdoMOPSOCheckBox = uicheckbox(app.GridLayout);
            app.UsarpadraoParametrosdoMOPSOCheckBox.ValueChangedFcn = createCallbackFcn(app, @atualizaUIvalor_padrao, true);
            app.UsarpadraoParametrosdoMOPSOCheckBox.Tag = 'ParametrosdoMOPSO';
            app.UsarpadraoParametrosdoMOPSOCheckBox.Text = 'Usar padrão';
            app.UsarpadraoParametrosdoMOPSOCheckBox.Value = true;
            app.IniciarotimizacaoButton = uibutton(app.GridLayout2, 'push');
            app.IniciarotimizacaoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarotimizacaoButtonPushed, true);
            app.IniciarotimizacaoButton.Text = 'Iniciar otimização';
            % Show the figure after all components are created
            app.OptimusViadutoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = otmapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptimusViadutoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptimusViadutoUIFigure)
        end
    end
end