classdef tabela_resultados_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UITable   matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, i, t, variaveis, fitness)
            set(app.UIFigure,'Name',['Informações sobre a solução ' num2str(i) ' da iteração ' num2str(t)])
            [~, legenda_longa, escala, unidade] = monta_legenda(length(variaveis));
            %app.UITable.Data = transpose([round(fitness(1),2) round(fitness(2),2) round(fitness(3),1) variaveis.*escala; 'R$' 'tCO2' 'anos' unidade]);
            %app.UITable.RowName = ['Custo de construção'  'Impacto ambiental' 'Vida útil de projeto' legenda_longa];
            app.UITable.Data = transpose(['Custo de construção'  'Impacto ambiental' 'Vida útil de projeto' legenda_longa; round(fitness(1),2) round(fitness(2),2) round(fitness(3),1) variaveis.*escala; 'R$' 'tCO2' 'anos' unidade]);
            %s = uistyle('HorizontalAlignment','center');
            %addStyle(app.UITable,s,'table','');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'Icone programa.png';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Propriedade'; 'Valor'; 'Unidade'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tabela_resultados_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end